classdef matlab_TCPIP_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATLABGUIUIFigure            matlab.ui.Figure
        ExportdatatoworkspaceButton  matlab.ui.control.Button
        x025secLabel                 matlab.ui.control.Label
        SubmitButton                 matlab.ui.control.Button
        TimewindowEditField          matlab.ui.control.NumericEditField
        TimewindowwidthsecLabel      matlab.ui.control.Label
        EditField_8                  matlab.ui.control.NumericEditField
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        UpperBoundLabel              matlab.ui.control.Label
        LowerBoundLabel              matlab.ui.control.Label
        EditField_1                  matlab.ui.control.NumericEditField
        Value4EditField              matlab.ui.control.NumericEditField
        Value4EditFieldLabel         matlab.ui.control.Label
        Value3EditField              matlab.ui.control.NumericEditField
        Value3EditFieldLabel         matlab.ui.control.Label
        Value2EditField              matlab.ui.control.NumericEditField
        Value2EditFieldLabel         matlab.ui.control.Label
        Value1EditField              matlab.ui.control.NumericEditField
        Value1EditField_2Label       matlab.ui.control.Label
        ClearPlotsButton             matlab.ui.control.Button
        SendtheabovevaluesButton     matlab.ui.control.Button
        SelectedVariableDropDown_4   matlab.ui.control.DropDown
        SelectedVariableDropDown_4Label  matlab.ui.control.Label
        SelectedVariableDropDown_3   matlab.ui.control.DropDown
        SelectedVariableDropDown_3Label  matlab.ui.control.Label
        SelectedVariableDropDown_2   matlab.ui.control.DropDown
        SelectedVariableDropDown_2Label  matlab.ui.control.Label
        SelectedVariableDropDown     matlab.ui.control.DropDown
        SelectedVariableLabel        matlab.ui.control.Label
        ConnecttoadeviceButton       matlab.ui.control.Button
        DisconnectfromadeviceButton  matlab.ui.control.Button
        ConnectionStatusLamp         matlab.ui.control.Lamp
        ConnectionStatusLabel_2      matlab.ui.control.Label
        IPaddressEditField           matlab.ui.control.EditField
        IPaddressEditFieldLabel      matlab.ui.control.Label
        PORTEditField                matlab.ui.control.EditField
        PORTEditFieldLabel           matlab.ui.control.Label
        RealTimePlotsignalsLabel     matlab.ui.control.Label
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        already_connected 

        maxSamples
        idx_time

        all_float_data

        xData_plot 
        yData_plot

        keep_idx
        odd_even
        time_window_width
        
        num_signals
        signals
        
        all_xData
        all_yData
        circular_idx
        maxAllSamples

        tcpObj
        tcpObj_sender
        plot_timer

        hline_1
        hline_2
        hline_3
        hline_4
    end
    
    methods (Access = private)
        
        function readDataCallback(app, src, event)

            %%% If the received data are bytes
            % Read all the data within the buffer
            data = ( read(app.tcpObj, (app.num_signals+1)*4, 'uint8') )';
            for i = 1:app.num_signals+1
                tmp_bunch_bytes = uint8( data( (i-1)*4+1:i*4 ) );

                % Use `typecast` to convert a bunch of 4 byte into a single float value
                app.all_float_data(i) = typecast(tmp_bunch_bytes, 'single');
                % If the remote system used an inverted order (big-endian)
                % app.all_float_data(i) = typecast(flip(tmp_bunch_bytes), 'single'); 
            end
            float_data = app.all_float_data(2:end);
            % disp(data')
            % disp(app.all_float_data)
            
            % Convert as a number all the received values
            isNan_flag = sum(isnan(float_data));
            if isNan_flag > 0
                disp('Dati non validi ricevuti');
                return;
            end

            % Saving of all received data (until the buffer is full)
            if app.circular_idx <= app.maxAllSamples
                app.circular_idx = mod(app.circular_idx-1, app.maxAllSamples)+1;
                app.all_xData(app.circular_idx,:) = app.all_float_data(1);
                app.all_yData(app.circular_idx,:) = float_data';
                app.circular_idx = app.circular_idx + 1;
                if app.circular_idx > app.maxAllSamples
                    app.circular_idx = 1;
                end
            end

            % Add incoming data to the saving vector and matrix
            if app.odd_even >= app.time_window_width
                app.xData_plot(1:end-1) = app.xData_plot(2:end);
                app.xData_plot(end)     = app.all_float_data(1);
                for i = 1:app.num_signals
                    app.yData_plot(1:end-1,i) = app.yData_plot(2:end,i);
                    app.yData_plot(end,i) = float_data(i);
                end
                app.odd_even = 0;
            end    
            app.odd_even = app.odd_even + 1;
        end
        
        function updatePlot(app)
            %%% Chosing which signal has to be plotted on the FIRST plot slot
            for i = 1:app.num_signals
                if strcmp(app.SelectedVariableDropDown.Value, app.signals(i))
                    set(app.hline_1, 'XData', app.xData_plot, 'YData', app.yData_plot(:,i));
                    break
                end
            end

            %%% Chosing which signal has to be plotted on the SECOND plot slot
            for i = 1:app.num_signals
                if strcmp(app.SelectedVariableDropDown_2.Value, app.signals(i))
                    set(app.hline_2, 'XData', app.xData_plot, 'YData', app.yData_plot(:,i));
                    break
                end
            end

            %%% Chosing which signal has to be plotted on the THIRD plot slot
            for i = 1:app.num_signals
                if strcmp(app.SelectedVariableDropDown_3.Value, app.signals(i))
                    set(app.hline_3, 'XData', app.xData_plot, 'YData', app.yData_plot(:,i));
                    break
                end
            end

            %%% Chosing which signal has to be plotted on the FOURTH plot slot
            for i = 1:app.num_signals
                if strcmp(app.SelectedVariableDropDown_4.Value, app.signals(i))
                    set(app.hline_4, 'XData', app.xData_plot, 'YData', app.yData_plot(:,i));
                    break
                end
            end

        end
        
        function clearPlotsFunction(app)
            app.xData_plot = nan(1, app.maxSamples);
            app.yData_plot = nan(app.maxSamples, app.num_signals); % Pre-allocation with NaN values
            
            app.all_xData = nan(app.maxAllSamples, 1);
            app.all_yData = nan(app.maxAllSamples, app.num_signals);
            app.circular_idx = 1;
        end
    end
    
    methods (Access = public)
        
        function all_XY_data = getTimeAndValue(app)
            all_XY_data = [app.all_xData, app.all_yData];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.maxSamples = 500;
            app.maxAllSamples = 500000; 
            app.idx_time = 0;
            app.num_signals = 8;
            
            app.all_float_data = zeros(1,app.num_signals+1);
            app.keep_idx = zeros(1,app.maxSamples);
            for i = 1:app.maxSamples
                app.keep_idx(i) = mod(i,2);
            end
            app.odd_even = 1;
            app.time_window_width = 20;
            
            app.clearPlotsFunction();
           
            hold(app.UIAxes, 'on'); app.UIAxes.Interactions = []; 
            disableDefaultInteractivity(app.UIAxes); app.UIAxes.Toolbar.Visible = 'off';
            hold(app.UIAxes_2, 'on'); app.UIAxes_2.Interactions = [];
            disableDefaultInteractivity(app.UIAxes_2); app.UIAxes_2.Toolbar.Visible = 'off';
            hold(app.UIAxes_3, 'on'); app.UIAxes_3.Interactions = [];
            disableDefaultInteractivity(app.UIAxes_3); app.UIAxes_3.Toolbar.Visible = 'off';
            hold(app.UIAxes_4, 'on'); app.UIAxes_4.Interactions = [];
            disableDefaultInteractivity(app.UIAxes_4); app.UIAxes_4.Toolbar.Visible = 'off';
            % Create plots only once
            app.hline_1 = plot(app.UIAxes,   app.xData_plot, app.yData_plot(:,1), 'b');
            app.hline_2 = plot(app.UIAxes_2, app.xData_plot, app.yData_plot(:,2), 'r');
            app.hline_3 = plot(app.UIAxes_3, app.xData_plot, app.yData_plot(:,3), 'g');
            app.hline_4 = plot(app.UIAxes_4, app.xData_plot, app.yData_plot(:,4), 'm');

            app.already_connected = 0;
            app.ConnectionStatusLamp.Color = [1.0, 0.0, 0.0]; 
            
            app.signals = {'Signal 1', 'Signal 2', 'Signal 3', 'Signal 4', 'Signal 5', ...
                           'Signal 6', 'Signal 7', 'Signal 8' };
            app.SelectedVariableDropDown.Items = app.signals;
            app.SelectedVariableDropDown.Value = 'Signal 1';
            app.SelectedVariableDropDown_2.Items = app.signals;
            app.SelectedVariableDropDown_2.Value = 'Signal 2';
            app.SelectedVariableDropDown_3.Items = app.signals;
            app.SelectedVariableDropDown_3.Value = 'Signal 3';
            app.SelectedVariableDropDown_4.Items = app.signals;
            app.SelectedVariableDropDown_4.Value = 'Signal 4';

        end

        % Button pushed function: DisconnectfromadeviceButton
        function DisconnectfromadeviceButtonPushed(app, event)
            if app.already_connected == 1
                clear app.tcpObj; % Close TCP connection
                disp('Connessione chiusa.');

                app.ConnectionStatusLamp.Color = [1.0, 0.0, 0.0];
                app.already_connected = 0;

                configureCallback(app.tcpObj, "off"); % Disable callback
                stop(app.plot_timer); % Stop and delete the timer
                delete(app.plot_timer);
                app.plot_timer = [];
            end
        end

        % Close request function: MATLABGUIUIFigure
        function MATLABGUIUIFigureCloseRequest(app, event)
            if app.already_connected == 1
                clear app.tcpObj; % Close TCP connection
                disp('Connessione chiusa.');

                app.ConnectionStatusLamp.Color = [1.0, 0.0, 0.0];
                app.already_connected = 0;

                configureCallback(app.tcpObj, "off"); % Disable callback
                stop(app.plot_timer); % Stop and delete the timer
                delete(app.plot_timer);
                app.plot_timer = [];
            end

            delete(app)
        end

        % Button pushed function: ConnecttoadeviceButton
        function ConnecttoadeviceButtonPushed(app, event)
            if app.already_connected == 0
                app.clearPlotsFunction();
                
                % IP address and port of the server TCP sender
                ipAddress = app.IPaddressEditField.Value;
                port = str2double(app.PORTEditField.Value);
     
                try
                    % TCP/IP client setup
                    app.tcpObj = tcpclient(ipAddress, port);
                
                    disp('First connection successfully established!');

                    configureCallback(app.tcpObj, "byte", (app.num_signals+1)*4, @(src, event) app.readDataCallback(src, event));
                    % Timer setup
                    % Plots updated every 0.017 seconds
                    app.plot_timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.02, ... 
                                           'TimerFcn', @(~,~) app.updatePlot()); 
                    start(app.plot_timer); % Enable the timer
    
                    % IP address and port of the server TCP receiver
                    ipAddress = app.IPaddressEditField.Value;
                    port = str2double(app.PORTEditField.Value)+1;
  
                    try
                        % TCP/IP client setup
                        app.tcpObj_sender = tcpclient(ipAddress, port);
                    
                        disp('Second connection successfully established!');
        
                        app.ConnectionStatusLamp.Color = [0.0, 1.0, 0.0];
                        app.already_connected = 1;
                    
                    catch ME
                        % Handling of connection errors
                        disp('Second connection error:');
                        disp(ME.message);
                        
                        app.ConnectionStatusLamp.Color = [1.00, 0.41, 0.16];
                    end
                
                catch ME
                    % Handling of connection errors
                    disp('First connection error:');
                    disp(ME.message);
                    
                    app.ConnectionStatusLamp.Color = [1.00, 0.41, 0.16];
                end
                
            else
                disp('TCP/IP communication already established!');
            end
        end

        % Button pushed function: SendtheabovevaluesButton
        function SendtheabovevaluesButtonPushed(app, event)
            if app.already_connected == 1 || 1
                values_to_send = single([ app.Value1EditField.Value, app.Value2EditField.Value, ...
                                          app.Value3EditField.Value, app.Value4EditField.Value ]);
                lower_bounds = [ app.EditField_1.Value, app.EditField_3.Value, ...
                                 app.EditField_5.Value, app.EditField_7.Value  ];
                upper_bounds = [ app.EditField_2.Value, app.EditField_4.Value, ...
                                 app.EditField_6.Value, app.EditField_8.Value  ];

                % Check if inserted values are inside the bounds
                lower_bound_flags = values_to_send < lower_bounds;
                upper_bound_flags = values_to_send > upper_bounds;
                bounds_not_exceeded = sum( lower_bound_flags + upper_bound_flags );

                if bounds_not_exceeded == 0
                    % Translate a float array in bytes
                    bytes_to_send = typecast(values_to_send, 'uint8');

                    write(app.tcpObj_sender, bytes_to_send)
                    disp('All the values entered respect their bounds !');
                    disp(bytes_to_send)

                    app.Value1EditField.BackgroundColor = [1.0, 1.0, 1.0];
                    app.Value2EditField.BackgroundColor = [1.0, 1.0, 1.0];
                    app.Value3EditField.BackgroundColor = [1.0, 1.0, 1.0];
                    app.Value4EditField.BackgroundColor = [1.0, 1.0, 1.0];
                else
                    for k = 1:4
                        if lower_bound_flags(k) == 1 || upper_bound_flags(k) == 1
                            if k == 1
                                app.Value1EditField.BackgroundColor = [1.0, 0.0, 0.0];
                            elseif k == 2
                                app.Value2EditField.BackgroundColor = [1.0, 0.0, 0.0];
                            elseif k == 3
                                app.Value3EditField.BackgroundColor = [1.0, 0.0, 0.0];
                            else
                                app.Value4EditField.BackgroundColor = [1.0, 0.0, 0.0];
                            end
                        else
                            if k == 1
                                app.Value1EditField.BackgroundColor = [1.0, 1.0, 1.0];
                            elseif k == 2
                                app.Value2EditField.BackgroundColor = [1.0, 1.0, 1.0];
                            elseif k == 3
                                app.Value3EditField.BackgroundColor = [1.0, 1.0, 1.0];
                            else
                                app.Value4EditField.BackgroundColor = [1.0, 1.0, 1.0];
                            end
                        end
                    end
                    disp('!!! ATTENION !!! One of the values entered does not respect its bounds !');
                end
            end
        end

        % Button pushed function: ClearPlotsButton
        function ClearPlotsButtonPushed(app, event)
            app.clearPlotsFunction();
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            if app.TimewindowEditField.Value >= 1
                app.time_window_width = app.TimewindowEditField.Value;
            end
        end

        % Button pushed function: ExportdatatoworkspaceButton
        function ExportdatatoworkspaceButtonPushed(app, event)
            to_workspace = app.getTimeAndValue();
            % Saving all stored values on matlab workspace 
            assignin('base', 'to_workspace', to_workspace);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATLABGUIUIFigure = uifigure('Visible', 'off');
            app.MATLABGUIUIFigure.Name = 'MATLAB GUI';
            app.MATLABGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @MATLABGUIUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.MATLABGUIUIFigure);
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.MATLABGUIUIFigure);
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.MATLABGUIUIFigure);
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.MATLABGUIUIFigure);
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.RealTimePlotsignalsLabel = uilabel(app.MATLABGUIUIFigure);
            app.RealTimePlotsignalsLabel.Text = 'Real-Time Plot signals';
            app.PORTEditFieldLabel = uilabel(app.MATLABGUIUIFigure);
            app.PORTEditFieldLabel.Text = 'PORT';
            app.PORTEditField = uieditfield(app.MATLABGUIUIFigure, 'text');
            app.PORTEditField.Value = '1200X';
            app.IPaddressEditFieldLabel = uilabel(app.MATLABGUIUIFigure);
            app.IPaddressEditFieldLabel.Text = 'IP address';
            app.IPaddressEditField = uieditfield(app.MATLABGUIUIFigure, 'text');
            app.IPaddressEditField.Value = '160.78.27.23X';
            app.ConnectionStatusLabel_2 = uilabel(app.MATLABGUIUIFigure);
            app.ConnectionStatusLabel_2.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.MATLABGUIUIFigure);
            app.DisconnectfromadeviceButton = uibutton(app.MATLABGUIUIFigure, 'push');
            app.DisconnectfromadeviceButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromadeviceButtonPushed, true);
            app.DisconnectfromadeviceButton.Text = 'Disconnect from a device';
            app.ConnecttoadeviceButton = uibutton(app.MATLABGUIUIFigure, 'push');
            app.ConnecttoadeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoadeviceButtonPushed, true);
            app.ConnecttoadeviceButton.Text = 'Connect to a device';
            app.SelectedVariableLabel = uilabel(app.MATLABGUIUIFigure);
            app.SelectedVariableLabel.Text = 'Selected Variable';
            app.SelectedVariableDropDown = uidropdown(app.MATLABGUIUIFigure);
            app.SelectedVariableDropDown.Items = {'- none -'};
            app.SelectedVariableDropDown.Value = '- none -';
            app.SelectedVariableDropDown_2Label = uilabel(app.MATLABGUIUIFigure);
            app.SelectedVariableDropDown_2Label.Text = 'Selected Variable';
            app.SelectedVariableDropDown_2 = uidropdown(app.MATLABGUIUIFigure);
            app.SelectedVariableDropDown_2.Items = {'- none -'};
            app.SelectedVariableDropDown_2.Value = '- none -';
            app.SelectedVariableDropDown_3Label = uilabel(app.MATLABGUIUIFigure);
            app.SelectedVariableDropDown_3Label.Text = 'Selected Variable';
            app.SelectedVariableDropDown_3 = uidropdown(app.MATLABGUIUIFigure);
            app.SelectedVariableDropDown_3.Items = {'- none -'};
            app.SelectedVariableDropDown_3.Value = '- none -';
            app.SelectedVariableDropDown_4Label = uilabel(app.MATLABGUIUIFigure);
            app.SelectedVariableDropDown_4Label.Text = 'Selected Variable';
            app.SelectedVariableDropDown_4 = uidropdown(app.MATLABGUIUIFigure);
            app.SelectedVariableDropDown_4.Items = {'- none -'};
            app.SelectedVariableDropDown_4.Value = '- none -';
            app.SendtheabovevaluesButton = uibutton(app.MATLABGUIUIFigure, 'push');
            app.SendtheabovevaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SendtheabovevaluesButtonPushed, true);
            app.SendtheabovevaluesButton.Text = 'Send the above values';
            app.ClearPlotsButton = uibutton(app.MATLABGUIUIFigure, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            app.Value1EditField_2Label = uilabel(app.MATLABGUIUIFigure);
            app.Value1EditField_2Label.Text = 'Value 1';
            app.Value1EditField = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.Value2EditFieldLabel = uilabel(app.MATLABGUIUIFigure);
            app.Value2EditFieldLabel.Text = 'Value 2';
            app.Value2EditField = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.Value3EditFieldLabel = uilabel(app.MATLABGUIUIFigure);
            app.Value3EditFieldLabel.Text = 'Value 3';
            app.Value3EditField = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.Value4EditFieldLabel = uilabel(app.MATLABGUIUIFigure);
            app.Value4EditFieldLabel.Text = 'Value 4';
            app.Value4EditField = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_1 = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_1.Editable = 'off';
            app.EditField_1.Enable = 'off';
            app.LowerBoundLabel = uilabel(app.MATLABGUIUIFigure);
            app.LowerBoundLabel.Text = 'Lower Bound';
            app.UpperBoundLabel = uilabel(app.MATLABGUIUIFigure);
            app.UpperBoundLabel.Text = 'Upper Bound';
            app.EditField_2 = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.Value = 1000;
            app.EditField_3 = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Enable = 'off';
            app.EditField_4 = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.Value = 1000;
            app.EditField_5 = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Enable = 'off';
            app.EditField_6 = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.Value = 1000;
            app.EditField_7 = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Enable = 'off';
            app.EditField_8 = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.EditField_8.Limits = [0 Inf];
            app.EditField_8.Value = 1000;
            app.TimewindowwidthsecLabel = uilabel(app.MATLABGUIUIFigure);
            app.TimewindowwidthsecLabel.Text = 'Time window width ( ';
            app.TimewindowEditField = uieditfield(app.MATLABGUIUIFigure, 'numeric');
            app.TimewindowEditField.Value = 20;
            app.SubmitButton = uibutton(app.MATLABGUIUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.x025secLabel = uilabel(app.MATLABGUIUIFigure);
            app.x025secLabel.Text = 'x 0.25 sec)';
            app.ExportdatatoworkspaceButton = uibutton(app.MATLABGUIUIFigure, 'push');
            app.ExportdatatoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdatatoworkspaceButtonPushed, true);
            app.ExportdatatoworkspaceButton.Text = 'Export data to workspace';
            % Show the figure after all components are created
            app.MATLABGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_TCPIP_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATLABGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATLABGUIUIFigure)
        end
    end
end